classdef EdulogLivePlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        PlottingArea      matlab.ui.container.Panel
        StartButton       matlab.ui.control.StateButton
        EventButton       matlab.ui.control.Button
        ExportDataButton  matlab.ui.control.Button
        ResetViewButton   matlab.ui.control.Button
        ClapButton        matlab.ui.control.StateButton
        ExportPlotButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        Port
        SPS
        Loggers
        Data
        Axes
        Lines
        Events
        Concern
        N
        Clap
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            
            loggertypes = {...
                'Pulse', ...
                'GSR', ...
                'EKG', ...
                'BloodPressure', ...
                'HandDynamometer', ...
                'Temperature', ...
                'Light', ...
                'Voltage', ...
                'Current', ...
                'PH', ...
                'Oxygen', ...
                'PhotoGate', ...
                'Force', ...
                'Sound', ...
                'Humidity', ...
                'Pressure', ...
                'Motion', ...
                'Magtnetic', ...
                'Conductivity', ...
                'CO2', ...
                'Barometer', ...
                'Rotary', ...
                'Acceleration', ...
                'Spirometer', ...
                'SoilMoisture', ...
                'Turbidity', ...
                'UVB', ...
                'Colorimeter', ...
                'DropCounter', ...
                'FlowRate', ...
                'ForcePlate', ...
                'Salinity', ...
                'UVA', ...
                'SurfaceTemp', ...
                'WideRangeTemp', ...
                'InfraredThermometer', ...
                'Respiration', ...
                'Calcium', ...
                'Chloride', ...
                'Ammonium', ...
                'Nitrate', ...
                'Anemometer', ...
                'GPS', ...
                'Gyroscope', ...
                'DewPoint', ...
                'Charge'  ...
                };
            
            fig = uifigure;
            fig.Position(3:4) = [200 375];
            uilabel(fig, 'Text', "Port", 'Position', [25, 345, 150, 25]);
            uieditfield(fig, 'Value', '22002', 'Position', [25, 325, 150, 20], 'Tag', 'port');
            uilabel(fig, 'Text', "Samples Per Second", 'Position', [25, 290, 150, 25]);
            uispinner(fig, 'Value', 5, 'Step', 1, 'Limits', [1,5], 'Position', [25, 270, 150, 20], 'Tag', 'sps');
            uilabel(fig, 'Text', "Loggers", 'Position', [25, 235, 150, 25]);
            uilistbox(fig, 'Items', loggertypes, 'Multiselect', 'on', 'Position', [25, 60, 150, 175], 'Tag', 'loggers');
            uibutton(fig, 'push', 'ButtonPushedFcn', @(src,event)app.Setup(fig), 'Text', "Okay", 'Position', [25, 15, 150, 25]);
            waitfor(fig)
            
            % Setup a blank figure
            sDim = get(0,'screensize'); % Get screensize
            app.UIFigure.Position([2,4]) = [100, sDim(4) - 200]; % Resize to the height of the screen - 200
            app.UIFigure.Position([1,3]) = [200, sDim(3) - 400]; % Resize to the width of the screen - 400
            app.PlottingArea.Position([2,4]) = [100, sDim(4) - 300];
            app.PlottingArea.Position([1,3]) = [0, sDim(3) - 400];
            app.UIFigure.Name = 'Edulog Data'; % Name figure
            app.UIFigure.NumberTitle = 'off'; % Remove "Figure 1" label
            app.UIFigure.Color = 'white'; % White background
            app.N = 2;
            
            app.UIFigure.Visible = 'on';
            
        end

        % Value changed function: StartButton
        function StartButtonPushed(app, event)
            app.Clap = [Inf, Inf];
            for L = 1:length(app.Loggers)
                switch event.Value
                    case true
                        app.Axes{L}.PickableParts = 'none';
                    case false
                        app.Axes{L}.PickableParts = 'all';
                end
            end
            preface = ['http://localhost:' num2str(app.Port) '/NeuLogAPI?']; % Construct the string to preface any argument passed to the Eduloggers
            while app.StartButton.Value == true
                n = app.N;
                tic; % Start a timer
                app.Data(n).Event = false;
                while toc < 1/app.SPS % Until the timer reaches sps^-1
                    for L = 1:length(app.Loggers) % For each logger...
                        raw = ... % Set the appropriate cell in data to equal...
                            webread( ... % ...the response received from the Edulogger when you send it...
                            [preface, 'GetSensorValue:[', app.Loggers{L}, '],[1]'] ... % ...this command: The preface, a request for values and the logger type
                            );
                        i = raw == '0' | ... % ...0
                            raw == '1' | ... % ...1
                            raw == '2' | ... % ...2
                            raw == '3' | ... % ...3
                            raw == '4' | ... % ...4
                            raw == '5' | ... % ...5
                            raw == '6' | ... % ...6
                            raw == '7' | ... % ...7
                            raw == '8' | ... % ...8
                            raw == '9' | ... % ...9
                            raw == '.' | ... % ...a decimal point
                            raw == '-';   ... % ...a minus sign
                            
                        app.Data(n).(app.Loggers{L}) = str2double(raw(i));
                    end
                    if app.Data(n).Time > sum(app.Clap)
                        sound(sin(1:5000), 10*1000);
                        EventButtonPushed(app, []);
                        app.ClapButton.Value = false;
                        app.Clap = [Inf, Inf];
                    end
                    app.Data(n).Time = app.Data(n-1).Time + toc; % Record the time taken
                    app.Data(n).Concern = round(toc, 1) > 2/app.SPS; % Did this sample take more than twice the desired time to retrieve?
                end
                for L = 1:length(app.Loggers)
                    app.Lines{L}.XData = [app.Lines{L}.XData, app.Data(n).Time];
                    app.Lines{L}.YData = [app.Lines{L}.YData, app.Data(n).(app.Loggers{L})];                    
                    if app.Data(n).Concern
                        line(app.Axes{L}, [app.Data(n).Time; app.Data(n).Time]', [-1000, 1000], 'Color', 'r', 'LineStyle', ':'); % Plot concern point
                    end
                end
                ResetView(app, event)
                drawnow
                app.N = n+1; % Iterate
            end

        end

        % Button pushed function: EventButton
        function EventButtonPushed(app, event)
            n = app.N;
            for L = 1:length(app.Loggers)
                line(app.Axes{L}, [app.Data(n).Time; app.Data(n).Time]', [-1000, 1000], 'Color', 'k', 'LineWidth', 2); % Plot event
            end
            app.Data(n).Event = true;
            drawnow
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            data = app.Data;
            [file,path] = uiputfile;
            file = strsplit(file, '.mldatx');
            file = file{1};
            save([path, file, '.mat'], 'data', '-mat');
        end

        % Button pushed function: ResetViewButton
        function ResetView(app, event)
            for L = 1:length(app.Loggers)
                app.Axes{L}.XLim = [app.N/app.SPS - 5, app.N/app.SPS + 5];
                app.Axes{L}.YLim = [min([app.Data.(app.Loggers{L})])-0.001, max([app.Data.(app.Loggers{L})])+0.0001];
            end
        end

        % Value changed function: ClapButton
        function ClapButtonValueChanged(app, event)
            if app.ClapButton.Value && app.StartButton.Value
                app.Clap = [app.Data(app.N).Time, rand()*20 + 10];
            else
                app.ClapButton.Value = false;
            end
            
        end

        % Callback function
        function Setup(app, event)
            port = findobj(event.Children, 'Tag', 'port');
            app.Port = port.Value;
            sps = findobj(event.Children, 'Tag', 'sps');
            app.SPS = sps.Value;
            loggers = findobj(event.Children, 'Tag', 'loggers');
            app.Loggers = loggers.Value;
            app.Data = struct(...
                'Time', 0, ...
                'Concern', true, ...
                'Event', true ...
                );
            
            % Setup axes
            m = min(length(app.Loggers), 3); % Determine number of rows (max 3)
            n = ceil(length(app.Loggers)/3); % Determine number of columns
            
            for L = 1:length(app.Loggers)
                % Starting data
                app.Data(1).(app.Loggers{L}) = 0;
                
                % Setup axis
                app.Axes{L} = subplot(m, n, L, 'Parent', app.PlottingArea); % Choose sub-plot to draw in
                app.Axes{L}.Position([1,3]) = [0.1, 0.8]; % Position plot
                app.Axes{L}.FontName = 'Verdana'; % Change font
                app.Axes{L}.XLabel.String = 'Time (s)'; % Label x-axis
                app.Axes{L}.YLabel.String = app.Loggers{L}; % Label y-axis
                app.Axes{L}.Color = [0.98, 0.98, 1]; % Axis background
                app.Axes{L}.XGrid = 'on'; % Add vertical gridlines
                app.Axes{L}.YGrid = 'on'; % Add horizontal gridlines
                app.Axes{L}.GridColor = 'white'; % Make gridlines white
                app.Axes{L}.GridAlpha = 1; % Make gridlines opaque
                app.Axes{L}.XLim = [-5, 5];
                
                % Plot data
                app.Lines{L} = line(app.Axes{L}, app.Data.Time(1), app.Data.(app.Loggers{L})(1), 'Color', [42, 107, 211]./255, 'LineWidth', 2); % Plot data
                line(app.Axes{L}, [0; 0]', [-1000, 1000], 'Color', 'k', 'LineWidth', 2); % Event (start)
                line(app.Axes{L}, [0; 0]', [-1000, 1000], 'Color', 'r', 'LineStyle', ':'); % Concern (start)
            end
            % Draw legend
                le = legend(app.Axes{1},{"Data", "Events", "Concern"}); % Add legend
                le.Position([1,3]) = [0.9, 0.1]; % Position legend
                le.Box = 'off'; % Remove outline
                le.AutoUpdate = 'off';
                le.Color = 'white';
                
                delete(event);
        
        end

        % Button pushed function: ExportPlotButton
        function ExportPlotButtonPushed(app, event)
            [file,path] = uiputfile;
            file = strsplit(file, '.mldatx');
            file = file{1};
            
            fig = figure('Visible', 'off');
            sDim = get(0,'screensize'); % Get screensize
            fig.Position([2,4]) = [100, sDim(4) - 200]; 
            fig.Position([1,3]) = [200, sDim(3) - 400];
            copyobj(app.PlottingArea, fig, 'legacy');
            
            fig.Visible = 'on';
            savefig(fig, [path, file, '.fig']);
            fig.Visible = 'off';
            close fig
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PlottingArea = uipanel(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EventButton = uibutton(app.UIFigure, 'push');
            app.EventButton.ButtonPushedFcn = createCallbackFcn(app, @EventButtonPushed, true);
            app.EventButton.Text = 'Event';
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.ResetViewButton = uibutton(app.UIFigure, 'push');
            app.ResetViewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetView, true);
            app.ResetViewButton.Text = 'Reset View';
            app.ClapButton = uibutton(app.UIFigure, 'state');
            app.ClapButton.ValueChangedFcn = createCallbackFcn(app, @ClapButtonValueChanged, true);
            app.ClapButton.Text = 'Clap';
            app.ExportPlotButton = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton.Text = 'Export Plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EdulogLivePlot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end